classdef ProjectAVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProgressTrackerEditField       matlab.ui.control.NumericEditField
        ProgressTrackerEditFieldLabel  matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        Button5c_2                     matlab.ui.control.Button
        Button6c_2                     matlab.ui.control.Button
        Button7c_2                     matlab.ui.control.Button
        Button9c_2                     matlab.ui.control.Button
        Button10d_2                    matlab.ui.control.Button
        Button10c_2                    matlab.ui.control.Button
        Button5c_1                     matlab.ui.control.Button
        Button6c_1                     matlab.ui.control.Button
        Button7c_1                     matlab.ui.control.Button
        Button9c_1                     matlab.ui.control.Button
        Button10c_1                    matlab.ui.control.Button
        Button10d_1                    matlab.ui.control.Button
        Button11a_2                    matlab.ui.control.Button
        Button11a_1                    matlab.ui.control.Button
        Button8b                       matlab.ui.control.Button
        Button8a                       matlab.ui.control.Button
        Button7a                       matlab.ui.control.Button
        Button7b                       matlab.ui.control.Button
        Button10b                      matlab.ui.control.Button
        Button10a                      matlab.ui.control.Button
        Button9b                       matlab.ui.control.Button
        Button9a                       matlab.ui.control.Button
        Button6b                       matlab.ui.control.Button
        Button6a                       matlab.ui.control.Button
        Button5b                       matlab.ui.control.Button
        Button5a                       matlab.ui.control.Button
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
%       simoutput5a;
    end

    
%     methods (Access = private)
%         
%         function startupFcn(app)
%          
%  
%             
%         end
%     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             
%             if app.Button5a.BackgroundColor == [0 1 0];
%                 app.Button5a.BackgroundColor = [1 0 0];
%             else
%                 app.Button5a.BackgroundColor = [0 1 0];
%             end
            load('net5a', 'net5a');
            load('net5b', 'net5b');
            load('net5c', 'net5c');
            load('net6a', 'net6a');
            load('net6b', 'net6b');
            load('net6c', 'net6c');
            load('net7a', 'net7a');
            load('net7b', 'net7b');
            load('net7c', 'net7c');
            load('net8a', 'net8a');
            load('net8b', 'net8b');
            load('net9a', 'net9a');
            load('net9b', 'net9b');
            load('net9c', 'net9c');
            load('net10a', 'net10a');
            load('net10b', 'net10b');
            load('net10c', 'net10c');
            load('net10d', 'net10d');
            load('net11a', 'net11a');

            testinput = readtable('testing_input-1.csv');
            testinput = testinput(:, [4 6 8 12 16 18 20 22 24 28 32 34 36 38 40 44 48 50 52 54 58 60 62 64 66 70 74 76 78 80 82 84 88 92 96 98 100 104]);
            testinput = table2array(testinput);
            
            simoutput5a = zeros(1, 9082);
            simoutput5b = zeros(1, 9082);
            simoutput5c = zeros(1, 9082);
            simoutput6a = zeros(1, 9082);
            simoutput6b = zeros(1, 9082);
            simoutput6c = zeros(1, 9082);
            simoutput7a = zeros(1, 9082);
            simoutput7b = zeros(1, 9082);
            simoutput7c = zeros(1, 9082);
            simoutput8a = zeros(1, 9082);
            simoutput8b = zeros(1, 9082);
            simoutput9a = zeros(1, 9082);
            simoutput9b = zeros(1, 9082);
            simoutput9c = zeros(1, 9082);
            simoutput10a = zeros(1, 9082);
            simoutput10b = zeros(1, 9082);
            simoutput10c = zeros(1, 9082);
            simoutput10d = zeros(1, 9082);
            simoutput11a = zeros(1, 9082);
           
%             for i = 1:9082
%                 simoutput5a(i) = i;
%             end


            %data_points = 104;                 % Both input and output variable count in a single data frame
            %averg_number = 1;                % If required averaging - the number of data frames required averaging

            i = 1;
            while(i < 9080)
                %i = 1;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                             
%                 ServerIP='130.127.88.141';      % If Rack no3 : 130.127.88.141  
%                 Port = 5893;                    % This value is set in the GTNET-SKT module in the RSCAD model (Check in there if differ use that port here)
%             
%                 f_rData_all(1, 1:data_points) = 0.0;
%                 client_data(1, 1:data_points) = 0.0;
%             
%                 Skt_Dev = tcpclient(ServerIP, Port);
%                 pause(0.1);
%             
%                 for j=1:averg_number
%             
%                     rData = read(Skt_Dev, 4*data_points, 'uint8');
%                     f_rData_all(1,:) = f_rData_all(1,:)+ swapbytes(typecast(rData, 'single'));
%                 end
%             
%                 for j=1:data_points
%                     client_data(1,j) = f_rData_all(1,j)/averg_number;
%                 end
%                 pause(0.1);
                %---------------------------------------------------------------------------------
                
                %testinput_1(1,:) = client_data(1,:);
                
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                %testinput = testinput(:, [4 6 8 12 16 18 20 22 24 28 32 34 36 38 40 44 48 50 52 54 58 60 62 64 66 70 74 76 78 80 82 84 88 92 96 98 100 104]);
                %testinput = table2array(testinput);
                
                simoutput5a(i) = net5a(testinput(i, [2 5])');
                if simoutput5a(i) <= 0.34
%                    simoutput5a(i) = 0;
                    if app.Button5a.BackgroundColor == [0 1 0]
                        app.Button5a.BackgroundColor = [1 0 0];
                        pause(0.2);                      
                    end
                else
%                    simoutput5a(i) = 1;
                    if app.Button5a.BackgroundColor == [1 0 0]
                        app.Button5a.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput5b(i) = net5b(testinput(i, [3 6])');
                if simoutput5b(i) <= 0.34
%                    simoutput5b(i) = 0;
                    if app.Button5b.BackgroundColor == [0 1 0]
                        app.Button5b.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput5b(i) = 1;
                    if app.Button5b.BackgroundColor == [1 0 0]
                        app.Button5b.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput5c(i) = net5c(testinput(i, [1 4])');
                if simoutput5c(i) <= 0.34
%                    simoutput5c(i) = 0;
                    if app.Button5c_1.BackgroundColor == [0 1 0]
                        app.Button5c_1.BackgroundColor = [1 0 0];
                        app.Button5c_2.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput5c(i) = 1;
                    if app.Button5c_1.BackgroundColor == [1 0 0]
                        app.Button5c_1.BackgroundColor = [0 1 0];
                        app.Button5c_2.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput6a(i) = net6a(testinput(i, [8 11])');
                if simoutput6a(i) <= 0.34
%                    simoutput6a(i) = 0;
                    if app.Button6a.BackgroundColor == [0 1 0]
                        app.Button6a.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput6a(i) = 1;
                    if app.Button6a.BackgroundColor == [1 0 0]
                        app.Button6a.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput6b(i) = net6b(testinput(i, [9 12])');
                if simoutput6b(i) <= 0.34
%                    simoutput6b(i) = 0;
                    if app.Button6b.BackgroundColor == [0 1 0]
                        app.Button6b.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput6b(i) = 1;
                    if app.Button6b.BackgroundColor == [1 0 0]
                        app.Button6b.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
 
                simoutput6c(i) = net6c(testinput(i, [7 10])');
                if simoutput6c(i) <= 0.34
%                    simoutput6c(i) = 0;
                    if app.Button6c_1.BackgroundColor == [0 1 0]
                        app.Button6c_1.BackgroundColor = [1 0 0];
                        app.Button6c_2.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput6c(i) = 1;
                    if app.Button6c_1.BackgroundColor == [1 0 0]
                        app.Button6c_1.BackgroundColor = [0 1 0];
                        app.Button6c_2.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end  
                
                simoutput7a(i) = net7a(testinput(i, [14 17])');
                if simoutput7a(i) <= 0.34
%                    simoutput7a(i) = 0;
                    if app.Button7a.BackgroundColor == [0 1 0]
                        app.Button7a.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput7a(i) = 1;
                    if app.Button7a.BackgroundColor == [1 0 0]
                        app.Button7a.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput7b(i) = net7b(testinput(i, [15 18])');
                if simoutput7b(i) <= 0.4
%                    simoutput7b(i) = 0;
                    if app.Button7b.BackgroundColor == [0 1 0]
                        app.Button7b.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput7b(i) = 1;
                    if app.Button7b.BackgroundColor == [1 0 0]
                        app.Button7b.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput7c(i) = net7c(testinput(i, [13 16])');
                if simoutput7c(i) <= 0.34
%                    simoutput7c(i) = 0;
                    if app.Button7c_1.BackgroundColor == [0 1 0]
                        app.Button7c_1.BackgroundColor = [1 0 0];
                        app.Button7c_2.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput7c(i) = 1;
                    if app.Button7c_1.BackgroundColor == [1 0 0]
                        app.Button7c_1.BackgroundColor = [0 1 0];
                        app.Button7c_2.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput8a(i) = net8a(testinput(i, [19 21])');
                if simoutput8a(i) <= 0.55
%                    simoutput8a(i) = 0;
                    if app.Button8a.BackgroundColor == [0 1 0]
                        app.Button8a.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput8a(i) = 1;
                    if app.Button8a.BackgroundColor == [1 0 0]
                        app.Button8a.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput8b(i) = net8b(testinput(i, [20 22])');
                if simoutput8b(i) <= 0.55
%                    simoutput8b(i) = 0;
                    if app.Button8b.BackgroundColor == [0 1 0]
                        app.Button8b.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput8b(i) = 1;
                    if app.Button8b.BackgroundColor == [1 0 0]
                        app.Button8b.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput9a(i) = net9a(testinput(i, [23 27])');
                if simoutput9a(i) <= 0.34
%                    simoutput9a(i) = 0;
                    if app.Button9a.BackgroundColor == [0 1 0]
                        app.Button9a.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput9a(i) = 1;
                    if app.Button9a.BackgroundColor == [1 0 0]
                        app.Button9a.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput9b(i) = net9b(testinput(i, [24 28])');
                if simoutput9b(i) <= 0.34
%                    simoutput9b(i) = 0;
                    if app.Button9b.BackgroundColor == [0 1 0]
                        app.Button9b.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput9b(i) = 1;
                    if app.Button9b.BackgroundColor == [1 0 0]
                        app.Button9b.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput9c(i) = net9c(testinput(i, [25 26])');
                if simoutput9c(i) <= 0.34
%                    simoutput9c(i) = 0;
                    if app.Button9c_1.BackgroundColor == [0 1 0]
                        app.Button9c_1.BackgroundColor = [1 0 0];
                        app.Button9c_2.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput9c(i) = 1;
                    if app.Button9c_1.BackgroundColor == [1 0 0]
                        app.Button9c_1.BackgroundColor = [0 1 0];
                        app.Button9c_2.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput10a(i) = net10a(testinput(i, [29 35])');
                if simoutput10a(i) <= 0.34
%                    simoutput10a(i) = 0;
                    if app.Button10a.BackgroundColor == [0 1 0]
                        app.Button10a.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput10a(i) = 1;
                    if app.Button10a.BackgroundColor == [1 0 0]
                        app.Button10a.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end                

                simoutput10b(i) = net10b(testinput(i, [30 36])');
                if simoutput10b(i) <= 0.34
%                    simoutput10b(i) = 0;
                    if app.Button10b.BackgroundColor == [0 1 0]
                        app.Button10b.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput10b(i) = 1;
                    if app.Button10b.BackgroundColor == [1 0 0]
                        app.Button10b.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput10c(i) = net10c(testinput(i, [31 33])');
                if simoutput10c(i) <= 0.4
%                    simoutput10c(i) = 0;
                    if app.Button10c_1.BackgroundColor == [0 1 0]
                        app.Button10c_1.BackgroundColor = [1 0 0];
                        app.Button10c_2.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput10c(i) = 1;
                    if app.Button10c_1.BackgroundColor == [1 0 0]
                        app.Button10c_1.BackgroundColor = [0 1 0];
                        app.Button10c_2.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end  
                
                simoutput10d(i) = net10d(testinput(i, [32 34])');
                if simoutput10d(i) <= 0.34
%                    simoutput10d(i) = 0;
                    if app.Button10d_1.BackgroundColor == [0 1 0]
                        app.Button10d_1.BackgroundColor = [1 0 0];
                        app.Button10d_2.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput10d(i) = 1;
                    if app.Button10d_1.BackgroundColor == [1 0 0]
                        app.Button10d_1.BackgroundColor = [0 1 0];
                        app.Button10d_2.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                
                simoutput11a(i) = net11a(testinput(i, [37 38])');
                if simoutput11a(i) <= 0.5
%                    simoutput11a(i) = 0;
                    if app.Button11a_1.BackgroundColor == [0 1 0]
                        app.Button11a_1.BackgroundColor = [1 0 0];
                        app.Button11a_2.BackgroundColor = [1 0 0];
                        pause(0.2);
                    end
                else
%                    simoutput11a(i) = 1;
                    if app.Button11a_1.BackgroundColor == [1 0 0]
                        app.Button11a_1.BackgroundColor = [0 1 0];
                        app.Button11a_2.BackgroundColor = [0 1 0];
                        pause(0.2);
                    end
                end
                app.ProgressTrackerEditField.Value = i;
                i = i + 1;
                pause(0.005)
            end 


%           app.Button5a.BackgroundColor = [0 0 1];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'BusDiagram.PNG';
            app.Button5a = uibutton(app.UIFigure, 'push');
            app.Button5a.Text = '';
            app.Button5b = uibutton(app.UIFigure, 'push');
            app.Button5b.Text = '';
            app.Button6a = uibutton(app.UIFigure, 'push');
            app.Button6a.Text = '';
            app.Button6b = uibutton(app.UIFigure, 'push');
            app.Button6b.Text = '';
            app.Button9a = uibutton(app.UIFigure, 'push');
            app.Button9a.Text = '';
            app.Button9b = uibutton(app.UIFigure, 'push');
            app.Button9b.Text = '';
            app.Button10a = uibutton(app.UIFigure, 'push');
            app.Button10a.Text = '';
            app.Button10b = uibutton(app.UIFigure, 'push');
            app.Button10b.Text = '';
            app.Button7b = uibutton(app.UIFigure, 'push');
            app.Button7b.Text = '';
            app.Button7a = uibutton(app.UIFigure, 'push');
            app.Button7a.Text = '';
            app.Button8a = uibutton(app.UIFigure, 'push');
            app.Button8a.Text = '';
            app.Button8b = uibutton(app.UIFigure, 'push');
            app.Button8b.Text = '';
            app.Button11a_1 = uibutton(app.UIFigure, 'push');
            app.Button11a_1.Text = '';
            app.Button11a_2 = uibutton(app.UIFigure, 'push');
            app.Button11a_2.Text = '';
            app.Button10d_1 = uibutton(app.UIFigure, 'push');
            app.Button10d_1.Text = '';
            app.Button10c_1 = uibutton(app.UIFigure, 'push');
            app.Button10c_1.Text = '';
            app.Button9c_1 = uibutton(app.UIFigure, 'push');
            app.Button9c_1.Text = '';
            app.Button7c_1 = uibutton(app.UIFigure, 'push');
            app.Button7c_1.Text = '';
            app.Button6c_1 = uibutton(app.UIFigure, 'push');
            app.Button6c_1.Text = '';
            app.Button5c_1 = uibutton(app.UIFigure, 'push');
            app.Button5c_1.Text = '';
            app.Button10c_2 = uibutton(app.UIFigure, 'push');
            app.Button10c_2.Text = '';
            app.Button10d_2 = uibutton(app.UIFigure, 'push');
            app.Button10d_2.Text = '';
            app.Button9c_2 = uibutton(app.UIFigure, 'push');
            app.Button9c_2.Text = '';
            app.Button7c_2 = uibutton(app.UIFigure, 'push');
            app.Button7c_2.Text = '';
            app.Button6c_2 = uibutton(app.UIFigure, 'push');
            app.Button6c_2.Text = '';
            app.Button5c_2 = uibutton(app.UIFigure, 'push');
            app.Button5c_2.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ProgressTrackerEditFieldLabel = uilabel(app.UIFigure);
            app.ProgressTrackerEditFieldLabel.Text = 'Progress Tracker';
            app.ProgressTrackerEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProgressTrackerEditField.ValueDisplayFormat = '%g';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectAVisualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end